classdef StallCatchersEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StallCatchersEditorUIFigure     matlab.ui.Figure
        FilePanel                       matlab.ui.container.Panel
        LoadSingleChStackedTIFFFileButton  matlab.ui.control.Button
        SaveProjectedTIFFFileButton     matlab.ui.control.Button
        SaveEditedAnalysisEditmatFileButton  matlab.ui.control.Button
        LoadAnalysismatFileButton       matlab.ui.control.Button
        AxisPanel                       matlab.ui.container.Panel
        DepthSlider                     matlab.ui.control.Slider
        DepthSliderLabel                matlab.ui.control.Label
        DepthEditField                  matlab.ui.control.NumericEditField
        UIAxesCursor                    matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        PanelSelectButtonGroup          matlab.ui.container.ButtonGroup
        ViewButton                      matlab.ui.control.ToggleButton
        EditButton                      matlab.ui.control.ToggleButton
        FileButton                      matlab.ui.control.ToggleButton
        EditPanel                       matlab.ui.container.Panel
        ConnectEndpointsPanel           matlab.ui.container.Panel
        ConnectingCButton               matlab.ui.control.StateButton
        RemoveSegmentsPanel             matlab.ui.container.Panel
        IndividualRButton               matlab.ui.control.StateButton
        SelectROISButton                matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        ROIExcludeButton                matlab.ui.control.Button
        DrawVesselLinesPanel            matlab.ui.container.Panel
        DrawLinesDButton                matlab.ui.control.StateButton
        EraserEButton                   matlab.ui.control.StateButton
        EraserWidthpxEditField          matlab.ui.control.NumericEditField
        EraserWidthpxLabel              matlab.ui.control.Label
        MinVesselLengthEditField        matlab.ui.control.NumericEditField
        MinVesselLengthpxEditFieldLabel  matlab.ui.control.Label
        RefreshVesselCenterlinesButton  matlab.ui.control.Button
        CutStackPanel                   matlab.ui.container.Panel
        CutStackButton                  matlab.ui.control.Button
        StopZEditField                  matlab.ui.control.NumericEditField
        StopZEditFieldLabel             matlab.ui.control.Label
        StartZEditField                 matlab.ui.control.NumericEditField
        StartZEditFieldLabel            matlab.ui.control.Label
        ViewPanel                       matlab.ui.container.Panel
        ZoomPanel                       matlab.ui.container.Panel
        FactorEditField                 matlab.ui.control.NumericEditField
        FactorEditFieldLabel            matlab.ui.control.Label
        DViewerPanel                    matlab.ui.container.Panel
        Plot3DZDepthEditField           matlab.ui.control.NumericEditField
        ZdepthpxLabel                   matlab.ui.control.Label
        Plot3DBkPctEditField            matlab.ui.control.NumericEditField
        BackgroundLabel                 matlab.ui.control.Label
        Plot3DXYWidthEditField          matlab.ui.control.NumericEditField
        XYwidthpxLabel                  matlab.ui.control.Label
        Plot3DButton                    matlab.ui.control.StateButton
        ProjectionDepthEditField        matlab.ui.control.NumericEditField
        ProjectionDepthLabel            matlab.ui.control.Label
        EndpointRadiusButton            matlab.ui.control.Button
        DepthPlaneCheckBox              matlab.ui.control.CheckBox
        EndPointsCheckBox               matlab.ui.control.CheckBox
        VesselsCheckBox                 matlab.ui.control.CheckBox
        DeepVessMaskCheckBox            matlab.ui.control.CheckBox
        LineColorsButton                matlab.ui.control.Button
        LineThicknessButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        inFile;             % Loaded file's name
        inPath;             % Loaded file's path
        vessThickness = 1;  % Skeleton voxel thickness
        endPtRadius = 1;    % Endpoint voxel radius
%         projXY = 1;         % Radius of cursor box for vessel/endpoint selection
        rgbDV = [0 0 1];    % Default line colouring for DeepVess Mask
        rgbVess = [1 0 0];  % Default line colouring for Vessel Centerlines
        rgbEnds = [0 1 0];  % Default line colouring for Endpoints
        rgbOmit = [1 0 1];  % Default line colouring for Excluded Vessels
        rgbAdd = [1 1 0];   % Default line colouring for Endpoint Connections
        
        nWidth = 100;       % image width
        nHeight = 100;      % image height
        nDepth = 100;       % image depth
        imData;             % Image data (uint16)
        dvData;             % DeepVess segmentation (logical)
        vessData;           % Numbered vessel segments
        endsData;           % Endpoints of vessel segments (logical)
        omitData;           % Vessel centerlines to be omitted (logical)
        addData;            % Vessel centerlines to be added (logical)
        zRange;             % Range of Z frames with respect to original data range
        um = [1 1 1];       % Microns per pixel of image stack
        minBranchLength = 10; % Minimum count of connected voxels and branches
        ROI;                % Current region of interest
        
        mouseButton = false(1,3);   % Stores the current state of the mouse buttons [left right center]
        keyState = false(1,1);      % Stores the current state of specific keys [ctrl]
        YXZ0 = [];          % Previous click point when drawing a series of lines
        
    end
    
    methods (Access = private)
        
        
        % Refresh vessData and endsData
        function refreshVessData(app)
            
            % Do nothing if no file loaded
            if isempty(app.inFile)
                return;
            end
            
            % Combine in any drawn vessel lines, then clear volumes
            tempVessData = (app.vessData>0) & ~app.omitData;
            tempVessData = tempVessData | app.addData;
            app.vessData = zeros(app.nHeight,app.nWidth,app.nDepth);
            app.omitData = false(app.nHeight,app.nWidth,app.nDepth);
            app.addData = false(app.nHeight,app.nWidth,app.nDepth);
            app.YXZ0 = [];
            
            % Re-skeletonize vessel data
            tempSkel = bwskel(tempVessData);
            % Remove junctions from vessel data
            tempSkel = tempSkel & ~bwmorph3(tempSkel, 'branchpoints');
            
            % Remove any segments below minBranchLength
            % Acts mostly on large vessel errors, but also removes shorter segments
            CC = bwconncomp(tempSkel);
            vessCount = 0;
            for i=1:CC.NumObjects
                if numel(CC.PixelIdxList{i}) >= app.minBranchLength
                    vessCount = vessCount + 1;
                    app.vessData(CC.PixelIdxList{i}) = vessCount;
                end
            end
            
            % Refresh endpoint data
            app.endsData = bwmorph3((app.vessData>0), 'endpoints');
            
        end
        
        
        % Refresh the currently displayed image according to View panel settings
        function updateImage(app)
            
            % Do nothing if no file loaded
            if isempty(app.inFile)
                return;
            end
            
            % Create RBG image
            imageZ = calcImageZ(app, app.DepthSlider.Value);
            
            % Display image at Z = iDepth
            imagesc(app.UIAxes, imageZ, 'HitTest','off'); % Allow click-through to UIAxes
            app.UIAxes.DataAspectRatio = [1 1 1];
            
            % Enforce axis limit max/min
            if app.UIAxes.XLim(1) < 1, app.UIAxes.XLim(1) = 1; end
            if app.UIAxes.XLim(2) > app.nWidth, app.UIAxes.XLim(2) = app.nWidth; end
            if app.UIAxes.YLim(1) < 1, app.UIAxes.YLim(1) = 1; end
            if app.UIAxes.YLim(2) > app.nHeight, app.UIAxes.YLim(2) = app.nHeight; end
            
        end
        
        
        % Calculate image frame based on user specified options
        function imageZ = calcImageZ(app, iDepth)
            
            % Current z-projection range
            rDepth = max([1 iDepth - app.ProjectionDepthEditField.Value]) ...
                : min([app.nDepth iDepth + app.ProjectionDepthEditField.Value]);
            
            % Create RBG image, greyscale image data
            frame = max(app.imData(:,:,rDepth),[],3);
            rFrame = frame;
            gFrame = frame;
            bFrame = frame;
            
            % Set projection intensity depending on if current plane is highlighted
            depthVal = intmax(class(frame));
            if app.DepthPlaneCheckBox.Value
                projVal = round(depthVal/2);
            else
                projVal = depthVal;
            end
            
            % DeepVess mask
            if app.DeepVessMaskCheckBox.Value
                
                frame = max(app.dvData(:,:,rDepth),[],3);
                rFrame(frame) = app.rgbDV(1)*rFrame(frame);
                gFrame(frame) = app.rgbDV(2)*gFrame(frame);
                bFrame(frame) = app.rgbDV(3)*bFrame(frame);
                
                if strcmp(app.DepthPlaneCheckBox.Value , 1)
                    frame = app.dvData(:,:,iDepth);
                    rFrame(frame) = app.rgbDV(1)*depthVal;
                    gFrame(frame) = app.rgbDV(2)*depthVal;
                    bFrame(frame) = app.rgbDV(3)*depthVal;
                end
                
            end
            
            % Vessel center lines
            if app.VesselsCheckBox.Value
                
                frame = imdilate((max(app.vessData(:,:,rDepth),[],3) > 0), ...
                    strel('disk',round(0.5*app.vessThickness)));
                rFrame(frame) = app.rgbVess(1)*projVal;
                gFrame(frame) = app.rgbVess(2)*projVal;
                bFrame(frame) = app.rgbVess(3)*projVal;
                
                if app.DepthPlaneCheckBox.Value
                    frame = imdilate((app.vessData(:,:,iDepth) > 0), ...
                        strel('disk',round(0.5*app.vessThickness)));
                    rFrame(frame) = app.rgbVess(1)*depthVal;
                    gFrame(frame) = app.rgbVess(2)*depthVal;
                    bFrame(frame) = app.rgbVess(3)*depthVal;
                end
                
            end
            
            % Endpoints
            if app.EndPointsCheckBox.Value
                
                frame = imdilate(max(app.endsData(:,:,rDepth),[],3), ...
                    strel('disk',round(app.endPtRadius)));
                rFrame(frame) = app.rgbEnds(1)*projVal;
                gFrame(frame) = app.rgbEnds(2)*projVal;
                bFrame(frame) = app.rgbEnds(3)*projVal;
                
                if app.DepthPlaneCheckBox.Value
                    frame = imdilate(app.endsData(:,:,iDepth), ...
                        strel('disk',round(app.endPtRadius)));
                    rFrame(frame) = app.rgbEnds(1)*depthVal;
                    gFrame(frame) = app.rgbEnds(2)*depthVal;
                    bFrame(frame) = app.rgbEnds(3)*depthVal;
                end
                
            end
            
            % Added vessel center lines
            if app.VesselsCheckBox.Value
                
                frame = imdilate(max(app.addData(:,:,rDepth),[],3), ...
                    strel('disk',round(0.5*app.vessThickness)));
                rFrame(frame) = app.rgbAdd(1)*projVal;
                gFrame(frame) = app.rgbAdd(2)*projVal;
                bFrame(frame) = app.rgbAdd(3)*projVal;
                
                if app.DepthPlaneCheckBox.Value
                    frame = imdilate(app.addData(:,:,iDepth), ...
                        strel('disk',round(0.5*app.vessThickness)));
                    rFrame(frame) = app.rgbAdd(1)*depthVal;
                    gFrame(frame) = app.rgbAdd(2)*depthVal;
                    bFrame(frame) = app.rgbAdd(3)*depthVal;
                end
                
            end
            
            % Omitted vessel center lines
            if app.VesselsCheckBox.Value
                
                frame = imdilate(max(app.omitData(:,:,rDepth),[],3), ...
                    strel('disk',round(0.5*app.vessThickness)));
                rFrame(frame) = app.rgbOmit(1)*projVal;
                gFrame(frame) = app.rgbOmit(2)*projVal;
                bFrame(frame) = app.rgbOmit(3)*projVal;
                
                if app.DepthPlaneCheckBox.Value
                    frame = imdilate(app.omitData(:,:,iDepth), ...
                        strel('disk',round(0.5*app.vessThickness)));
                    rFrame(frame) = app.rgbOmit(1)*depthVal;
                    gFrame(frame) = app.rgbOmit(2)*depthVal;
                    bFrame(frame) = app.rgbOmit(3)*depthVal;
                end
                
            end
            
            imageZ(:,:,1) = rFrame;
            imageZ(:,:,2) = gFrame;
            imageZ(:,:,3) = bFrame;
            
        end
        
        
        % Convert a rgb array, with values between 0 and 1, to a 
        % hexidecimal character array
        function hex = rgb2hex(~, rgb)
            
            hex = ['#' sprintf('%02X',round(rgb*255))]; 
            
        end
        
        
        % Convert a hexidecimal character array to a rgb array
        % with values between 0 and 1
        function rgb = hex2rgb(~, hex)
            
            if strcmpi(hex(1),'#')
                hex(1) = [];
            end
            rgb = (sscanf(hex,'%02X')/255)';
            
        end
        
        
        % Flag vessel center lines for removal
        function eraseVessData(app, eraserX, eraserY)
            
            % Current z-projection range
            iDepth = app.DepthSlider.Value;
            rangeZ = max([1 iDepth - app.ProjectionDepthEditField.Value]) ...
                : min([app.nDepth iDepth + app.ProjectionDepthEditField.Value]);
            
            % Current view limits
            XLim = round(app.UIAxes.XLim);
            YLim = round(app.UIAxes.YLim);
            
            % Check limits of square in current view limits
            rangeX = max([XLim(1) eraserX(1)]):min([XLim(2) eraserX(2)]);
            rangeY = max([YLim(1) eraserY(1)]):min([YLim(2) eraserY(2)]);
            
            % Remove any drawn lines
            app.addData(rangeY,rangeX,rangeZ) = false;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Modify axis interactivity
            % app.tbAxis = axtoolbar(app.UIAxes,{'datacursor','pan','zoomin','zoomout','restoreview'});
            axtoolbar(app.UIAxes,{});
            
            app.UIAxesCursor.Color = 'none';
            app.UIAxesCursor.HitTest = 'off';
            linkaxes([app.UIAxes app.UIAxesCursor]);
            
%             EraserRadiuspxEditFieldValueChanged(app);
            
            % Read in config file, if it exists
            if isfile('StallCatchersEditorConfig.mat')
                
                load('StallCatchersEditorConfig.mat'); %#ok<LOAD> 
                
                if exist('lineColours', 'var')
                    app.rgbDV = lineColours(1,:); %#ok<*USENS> 
                    app.rgbVess = lineColours(2,:);
                    app.rgbEnds = lineColours(3,:);
                    app.rgbOmit = lineColours(4,:);
                    app.rgbAdd = lineColours(5,:);
                end
                
                if exist('lineWidth', 'var')
                    app.vessThickness = lineWidth;
                end
                
                if exist('endRadius', 'var')
                    app.endPtRadius = endRadius;
                end
                
                if exist('viewOptions', 'var')
                    app.DeepVessMaskCheckBox.Value = viewOptions{1};
                    app.VesselsCheckBox.Value = viewOptions{2};
                    app.EndPointsCheckBox.Value = viewOptions{3};
                    app.DepthPlaneCheckBox.Value = viewOptions{4};
                end
                
            end
            
        end

        % Size changed function: StallCatchersEditorUIFigure
        function StallCatchersEditorUIFigureSizeChanged(app, event)
            pos = app.StallCatchersEditorUIFigure.Position;
            
            hGroup = 89;
            wPanel = 130;
            hPanel = 524;
            
            % Keep all panel their original width
            app.PanelSelectButtonGroup.Position = [1 pos(4)-hGroup+1 wPanel hGroup];
            app.FilePanel.Position = [1 pos(4)-hGroup-hPanel+2 wPanel hPanel];
            app.EditPanel.Position = [1 pos(4)-hGroup-hPanel+2 wPanel hPanel];
            app.ViewPanel.Position = [1 pos(4)-hGroup-hPanel+2 wPanel hPanel];
            app.AxisPanel.Position = [wPanel 1 pos(3)-wPanel+2 pos(4)];
            app.DepthSlider.Position(4) = app.AxisPanel.Position(4)-89;
            app.UIAxes.Position = [60 1 app.AxisPanel.Position(3)-60 app.AxisPanel.Position(4)];
            app.UIAxesCursor.Position = app.UIAxes.Position;
            
        end

        % Key press function: StallCatchersEditorUIFigure
        function StallCatchersEditorUIFigureKeyPress(app, event)
            key = event.Key;
            
            switch upper(key)
                
                case 'R'
                    app.IndividualRButton.Value = ~app.IndividualRButton.Value;
                    IndividualRButtonValueChanged(app);
                    
                case 'C'
                    app.ConnectingCButton.Value = ~app.ConnectingCButton.Value;
                    ConnectingCButtonValueChanged(app);
                    
                case 'D'
                    app.DrawLinesDButton.Value = ~app.DrawLinesDButton.Value;
                    DrawLinesDButtonValueChanged(app);
                    
                case 'E'
                    app.EraserEButton.Value = ~app.EraserEButton.Value;
                    EraserEButtonValueChanged(app);
                
                case 'UPARROW'
                    app.DepthEditField.Value = app.DepthEditField.Value + 1;
                    DepthEditFieldValueChanged(app);
                
                case 'DOWNARROW'
                    app.DepthEditField.Value = app.DepthEditField.Value - 1;
                    DepthEditFieldValueChanged(app);
                    
                case 'S'
                    app.SelectROISButtonPushed(app);
                    
                case 'X'
                    app.ROIExcludeButtonPushed(app);
                    
                case 'SHIFT'
                    app.keyState(1) = true;

            end
            
        end

        % Key release function: StallCatchersEditorUIFigure
        function StallCatchersEditorUIFigureKeyRelease(app, event)
            
            switch upper(event.Key)
                case 'SHIFT'
                    app.keyState(1) = false;
            end
            
        end

        % Window button down function: StallCatchersEditorUIFigure
        function StallCatchersEditorUIFigureWindowButtonDown(app, event)
            
            switch event.EventName
                % Update mouse button state
                case 'WindowMousePress'
                switch event.Source.SelectionType
                    case 'normal'
                        app.mouseButton(1) = true;
                    case 'alt'
                        app.mouseButton(2) = true;
                    case 'extend'
                        app.mouseButton(3) = true;
                end
            end
            % disp(app.mouseButton);
            
        end

        % Window button up function: StallCatchersEditorUIFigure
        function StallCatchersEditorUIFigureWindowButtonUp(app, event)
            
            switch event.EventName
                % Update mouse button state
                case 'WindowMouseRelease'
                switch event.Source.SelectionType
                    case 'normal'
                        app.mouseButton(1) = false;
                    case 'alt'
                        app.mouseButton(2) = false;
                    case 'extend'
                        app.mouseButton(3) = false;
                end
            end
            % disp(app.mouseButton);
            
        end

        % Window scroll wheel function: StallCatchersEditorUIFigure
        function StallCatchersEditorUIFigureWindowScrollWheel(app, event)
            
            % Do nothing if no data is loaded
            if isempty(app.vessData)
                return
            end
            
            % Grab location wrt UIAxes
            X = ceil(app.UIAxes.CurrentPoint(1,1));
            Y = ceil(app.UIAxes.CurrentPoint(1,2));
            XLim = app.UIAxes.XLim;
            YLim = app.UIAxes.YLim;
            
            % Skip if mouse not over UIAxes
            if X<XLim(1) || X>XLim(2) || Y<YLim(1) || Y>YLim(2)
                return;
            end

            if app.keyState(1) == 1
            % Check if <ctrl> is held down

                % Range (take largest of X and Y, since data aspect is 1:1)
                rXY = max([XLim(2)-XLim(1) YLim(2)-YLim(1)]);

                % Calclulate new low XY limit, wrt mouse XY
                if event.VerticalScrollCount < 0
                    % Zoom in
                    X0 = X - (X-XLim(1))/app.FactorEditField.Value;
                    Y0 = Y - (Y-YLim(1))/app.FactorEditField.Value;
                    rXY = round(rXY/app.FactorEditField.Value);
                else
                    % Zoom out
                    X0 = X - (X-XLim(1))*app.FactorEditField.Value;
                    Y0 = Y - (Y-YLim(1))*app.FactorEditField.Value;
                    rXY = round(rXY*app.FactorEditField.Value);
                end

                % Zoom centered on mouse pointer
                app.UIAxes.XLim = [max([1 X0]) min([app.nWidth X0+rXY])];
                app.UIAxes.YLim = [max([1 Y0]) min([app.nHeight Y0+rXY])];

            else
            % <ctrl> is not held down

                % Change depth
                value = app.DepthEditField.Value + event.VerticalScrollCount;
                if value < 1
                    value = 1;
                elseif value > app.nDepth
                    value = app.nDepth;
                end
                
                app.DepthEditField.Value = value;
                app.DepthSlider.Value = value;

            end
            
            % Update displayed image
            updateImage(app);
            
        end

        % Window button motion function: StallCatchersEditorUIFigure
        function StallCatchersEditorUIFigureWindowButtonMotion(app, event)
            
            % Do nothing if no data is loaded
            if isempty(app.vessData)
                return
            end
            
            % Modify mouse pointer over axes
            X = ceil(app.UIAxes.CurrentPoint(1,1));
            Y = ceil(app.UIAxes.CurrentPoint(1,2));
            XLim = app.UIAxes.XLim;
            YLim = app.UIAxes.YLim;
            
            % Check if mousing over UIAxes
            if X>=XLim(1) && X<=XLim(2) && Y>=YLim(1) && Y<=YLim(2)
                
                % Change mouse pointer when hovering over UIAxes
                app.StallCatchersEditorUIFigure.Pointer = 'cross';
                
                if app.EraserEButton.Value % Erasing
                    
                    % Draw square eraser cursor
                    cX = ceil(app.UIAxesCursor.CurrentPoint(1,1));
                    cY = ceil(app.UIAxesCursor.CurrentPoint(1,2));
                    W = app.EraserWidthpxEditField.Value;
                    HW = round(0.5*W);
                    
                    cla(app.UIAxesCursor);
                    drawrectangle(app.UIAxesCursor, 'Position',[cX-HW cY-HW W W], ...
                        'Color','b', 'InteractionsAllowed','none');
                    
                    if app.mouseButton(1)
                        
                        % Erase vessel with projected square
                        eraserX = [X-HW X+HW];
                        eraserY = [Y-HW Y+HW];
                        
                        % Erase any drawn lines within current view
                        eraseVessData(app, eraserX, eraserY);
                        
%                     elseif app.mouseButton(2)
                        
                    end
                    
                    % Update displayed image
                    updateImage(app);
                    
                end
                
            else
                % Not mousing over UIAxes
                app.StallCatchersEditorUIFigure.Pointer = 'arrow';
            end
            
        end

        % Selection changed function: PanelSelectButtonGroup
        function PanelSelectButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.PanelSelectButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'File'
                    app.FilePanel.Visible = true;
                    app.EditPanel.Visible = false;
                    app.ViewPanel.Visible = false;
                case 'Edit'
                    app.FilePanel.Visible = false;
                    app.EditPanel.Visible = true;
                    app.ViewPanel.Visible = false;
                case 'View'
                    app.FilePanel.Visible = false;
                    app.EditPanel.Visible = false;
                    app.ViewPanel.Visible = true;
            end
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            % Do nothing if no data is loaded
            if isempty(app.vessData)
                return
            end
            
            X = ceil(app.UIAxes.CurrentPoint(1,1));
            Y = ceil(app.UIAxes.CurrentPoint(1,2));
            Z = round(app.DepthEditField.Value);
            
            switch event.Button
                
                case 1 % LEFT CLICK
                    
                    if app.Plot3DButton.Value
                        % Select center of 3D plot
                        
                        dXY = round(0.5*app.Plot3DXYWidthEditField.Value);
                        dZ = round(0.5*app.Plot3DZDepthEditField.Value);
                        subVol = app.imData(max([1 Y-dXY]):min([app.nHeight Y+dXY]), ...
                            max([1 X-dXY]):min([app.nWidth X+dXY]), ...
                            max([1 Z-dZ]):min([app.nDepth Z+dZ]));
                        
                        nZeros = round(app.Plot3DBkPctEditField.Value*256/100);
                        alphaMap = [zeros(nZeros,1); linspace(0,1,256-nZeros)'];
                        
                        subVol2 = flip(subVol,3);
                        subVol2 = flip(subVol2,2);
%                         volshow(subVol2,'Alphamap',alphaMap, 'CameraPosition',[4 4 2.5], ...
%                             'ScaleFactors',app.um);
                        hVS = volshow(subVol2,'Alphamap',alphaMap);
                        vVS = hVS.Parent;
                        vVS.CameraPosition = [4 4 2.5];
                        
                        app.Plot3DButton.Value = false;
                        
                    elseif app.DrawLinesDButton.Value % Drawing new lines
                        
                        % Draw a series of lines
                        if isempty(app.YXZ0)
                            
                            app.YXZ0 = [Y X Z];
                            
                        else
                            
                            % Draw a line from previous point
                            nLine = max(abs([Y X Z] - app.YXZ0));
                            iY = app.YXZ0(1) + round((0:nLine)*(Y - app.YXZ0(1))/nLine);
                            iX = app.YXZ0(2) + round((0:nLine)*(X - app.YXZ0(2))/nLine);
                            iZ = app.YXZ0(3) + round((0:nLine)*(Z - app.YXZ0(3))/nLine);
                            iYXZ = sub2ind([app.nHeight app.nWidth app.nDepth], iY,iX,iZ);
                            app.addData(iYXZ) = true;
                            
                            % Store current point and update displayed image
                            app.YXZ0 = [Y X Z];
                            updateImage(app);
                            
                        end
                        
                    elseif app.EraserEButton.Value % Erasing drawn lines
                        
                        if app.mouseButton(1)
                            
                            % Erase vessel with projected square
                            HW = round(0.5*app.EraserWidthpxEditField.Value);
                            eraserX = [X-HW X+HW];
                            eraserY = [Y-HW Y+HW];
                            
                            % Erase any drawn lines within current view
                            eraseVessData(app, eraserX, eraserY);
                            
                            % Update displayed image
                            updateImage(app);
                            
                        end
                        
                    elseif app.ConnectingCButton.Value % Connecting endpoints
                        
                        % Find closest endpoint and shift [Y X Z] to it
                        % Check X/Y +- endPtRadius at current Z+-ProjDepth
                        projZ = app.ProjectionDepthEditField.Value;
                        projXY = app.endPtRadius;
                        ZRange = max([1 Z-projZ]):min([app.nDepth Z+projZ]);
                        XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                        YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                        indEnd = find(app.endsData(YRange,XRange,ZRange));
                        if isempty(indEnd)
                            % Check at X/Y +- 2*endPtRadius in current Z-projection
                            projXY = 2*app.endPtRadius;
                            XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                            YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                            indEnd = find(app.endsData(YRange,XRange,ZRange));
                            if isempty(indEnd)
                                % Check at X/Y +- 4*endPtRadius in current Z-projection
                                projXY = 4*app.endPtRadius;
                                XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                                YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                                indEnd = find(app.endsData(YRange,XRange,ZRange));
                                if isempty(indEnd)
                                    % Give up
                                    return;
                                end
                            end
                        end
                        
                        % Find index closest to [Y X Z]
                        sz = [size(YRange,2) size(XRange,2) size(ZRange,2)];
                        dist2 = 1e10;
                        for i = 1:size(indEnd)
                            % Translate to full indices
                            [iY,iX,iZ] = ind2sub(sz, indEnd(i));
                            iY = iY + min(YRange) - 1;
                            iX = iX + min(XRange) - 1;
                            iZ = iZ + min(ZRange) - 1;
                            % Check if it's closer to original [Y X Z]
                            iDist2 = (iY-Y)^2 + (iX-X)^2 + (iZ-Z)^2;
                            if iDist2 < dist2
                                mY = iY; mX = iX; mZ = iZ;
                                dist2 = iDist2;
                            end
                            
                        end
                        
                        % Shift [Y X Z] to closest endpoint
                        Y = mY;
                        X = mX;
                        Z = mZ;
                        
                        % Pick two endpoints and connect them
                        if isempty(app.YXZ0)
                            
                            % Add selected endpoint to addData
                            iYXZ = sub2ind([app.nHeight app.nWidth app.nDepth], Y,X,Z);
                            app.addData(iYXZ) = true;
                            
                            % Save the first point for a new pair and update displayed image
                            app.YXZ0 = [Y X Z];
                            updateImage(app);
                            
                        else
                            
                            % Draw a line from previous point
                            nLine = max(abs([Y X Z] - app.YXZ0));
                            iY = app.YXZ0(1) + round((0:nLine)*(Y - app.YXZ0(1))/nLine);
                            iX = app.YXZ0(2) + round((0:nLine)*(X - app.YXZ0(2))/nLine);
                            iZ = app.YXZ0(3) + round((0:nLine)*(Z - app.YXZ0(3))/nLine);
                            iYXZ = sub2ind([app.nHeight app.nWidth app.nDepth], iY,iX,iZ);
                            app.addData(iYXZ) = true;
                            
                            % Clear first point for a new pair and update displayed image
                            app.YXZ0 = [];
                            updateImage(app);
                            
                        end
                        
                    elseif app.IndividualRButton.Value % Removing individual segments
                        
                        % Find closest vessel voxel and grabs its vessel number
                        % Check X/Y +- endPtRadius at current Z+-ProjDepth
                        projZ = app.ProjectionDepthEditField.Value;
                        projXY = app.endPtRadius;
                        ZRange = max([1 Z-projZ]):min([app.nDepth Z+projZ]);
                        XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                        YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                        indEnd = find(app.vessData(YRange,XRange,ZRange));
                        if isempty(indEnd)
                            % Check at X/Y +- 2*endPtRadius in current Z-projection
                            projXY = 2*app.endPtRadius;
                            XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                            YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                            indEnd = find(app.vessData(YRange,XRange,ZRange));
                            if isempty(indEnd)
                                % Check at X/Y +- 4*endPtRadius in current Z-projection
                                projXY = 4*app.endPtRadius;
                                XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                                YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                                indEnd = find(app.vessData(YRange,XRange,ZRange));
                                if isempty(indEnd)
                                    % Give up
                                    return;
                                end
                            end
                        end
                        
                        % Find index closest to [Y X Z]
                        sz = [size(YRange,2) size(XRange,2) size(ZRange,2)];
                        dist2 = 1e10;
                        for i = 1:size(indEnd)
                            % Translate to full indices
                            [iY,iX,iZ] = ind2sub(sz, indEnd(i));
                            iY = iY + min(YRange) - 1;
                            iX = iX + min(XRange) - 1;
                            iZ = iZ + min(ZRange) - 1;
                            % Check if it's closer to original [Y X Z]
                            iDist2 = (iY-Y)^2 + (iX-X)^2 + (iZ-Z)^2;
                            if iDist2 < dist2
                                mY = iY; mX = iX; mZ = iZ;
                                dist2 = iDist2;
                            end
                            
                        end
                        
                        % Remove segment from this vessel number
                        vessNum = app.vessData(mY,mX,mZ);
                        app.omitData = app.omitData | (app.vessData == vessNum);
                        
                        % Update displayed image
                        updateImage(app);
                        
                    end
                    
                case 3 % RIGHT CLICK
                    
                    if app.DrawLinesDButton.Value
                        % Clear 'previous cursor point'
                        app.YXZ0 = [];
                        
                    elseif app.ConnectingCButton.Value
                        
                        if ~isempty(app.YXZ0)
                            % Remove the last selected endpoint from addData and clear first selected endpoint
                            iYXZ = sub2ind([app.nHeight app.nWidth app.nDepth], app.YXZ0(1),app.YXZ0(2),app.YXZ0(3));
                            app.addData(iYXZ) = false;
                            app.YXZ0 = [];
                            
                            % Update displayed image
                            updateImage(app);
                            
                        end
                    
                    elseif app.IndividualRButton.Value % Removing individual segments from omitted vessels
                        
                        % Find closest vessel voxel and grabs its vessel number
                        % Check X/Y +- endPtRadius at current Z+-ProjDepth
                        projZ = app.ProjectionDepthEditField.Value;
                        projXY = app.endPtRadius;
                        ZRange = max([1 Z-projZ]):min([app.nDepth Z+projZ]);
                        XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                        YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                        indEnd = find(app.vessData(YRange,XRange,ZRange));
                        if isempty(indEnd)
                            % Check at X/Y +- 2*endPtRadius in current Z-projection
                            projXY = 2*app.endPtRadius;
                            XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                            YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                            indEnd = find(app.vessData(YRange,XRange,ZRange));
                            if isempty(indEnd)
                                % Check at X/Y +- 4*endPtRadius in current Z-projection
                                projXY = 4*app.endPtRadius;
                                XRange = max([1 X-projXY]):min([app.nWidth X+projXY]);
                                YRange = max([1 Y-projXY]):min([app.nHeight Y+projXY]);
                                indEnd = find(app.vessData(YRange,XRange,ZRange));
                                if isempty(indEnd)
                                    % Give up
                                    return;
                                end
                            end
                        end
                        
                        % Find index closest to [Y X Z]
                        sz = [size(YRange,2) size(XRange,2) size(ZRange,2)];
                        dist2 = 1e10;
                        for i = 1:size(indEnd)
                            % Translate to full indices
                            [iY,iX,iZ] = ind2sub(sz, indEnd(i));
                            iY = iY + min(YRange) - 1;
                            iX = iX + min(XRange) - 1;
                            iZ = iZ + min(ZRange) - 1;
                            % Check if it's closer to original [Y X Z]
                            iDist2 = (iY-Y)^2 + (iX-X)^2 + (iZ-Z)^2;
                            if iDist2 < dist2
                                mY = iY; mX = iX; mZ = iZ;
                                dist2 = iDist2;
                            end
                            
                        end
                        
                        % Remove segment from this vessel number
                        vessNum = app.vessData(mY,mX,mZ);
                        app.omitData = app.omitData & ~(app.vessData == vessNum);
                        
                        % Update displayed image
                        updateImage(app);
                        
                    end
                    
            end
            
        end

        % Button pushed function: LoadAnalysismatFileButton
        function LoadAnalysismatFileButtonPushed(app, event)
            
            % Load DeepVess Analysis .mat file
            if isempty(app.inPath)
                [inFile0,inPath0] = uigetfile('Analysis-*.mat','Select Analysis .mat file');
            else
                [inFile0,inPath0] = uigetfile(fullfile(app.inPath,'Analysis-*.mat'),'Select Analysis .mat file');
            end
            figure(app.StallCatchersEditorUIFigure);
            if inFile0 == 0
                return;
            else
                app.inFile = inFile0;
                app.inPath = inPath0;
            end
            
            % Focus back to app
            app.StallCatchersEditorUIFigure.Visible = 'off';
            app.StallCatchersEditorUIFigure.Visible = 'on';
            
            % Load file
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
                'Message','Loading file', 'Indeterminate','on');
            listOfVariables = who('-file', fullfile(app.inPath, app.inFile));
            % Image data
            load(fullfile(app.inPath, app.inFile), 'im');
            [app.nHeight,app.nWidth,app.nDepth] = size(im);
            app.imData = im;
            % Skel structure
            load(fullfile(app.inPath, app.inFile), 'Skel');
            % Z-range of current image data versus original image data
            if ismember('ZRange', listOfVariables)
                load(fullfile(app.inPath, app.inFile), 'ZRange');
                app.zRange = ZRange;
            else
                app.zRange = [1 app.nDepth];
            end
            % Load DeepVess segmentation, if present
            if ismember('V', listOfVariables)
                load(fullfile(app.inPath, app.inFile), 'V');
                app.dvData = logical(V);
            else
                app.dvData = false(app.nHeight,app.nWidth,app.nDepth);
            end
            % Create editing volumes
            app.omitData = false(app.nHeight,app.nWidth,app.nDepth);
            app.addData = false(app.nHeight,app.nWidth,app.nDepth);
            
            close(d);
            
            % Construct vessel data from Skel structure
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
                'Message','Extracting vessel paths', 'Value',0);
            app.vessData = zeros(app.nHeight,app.nWidth,app.nDepth);
            nSkel = size(Skel,2);
            for i = 1:nSkel
                if mod(i,10) == 0, d.Value = i/nSkel; end
                idx = sub2ind([app.nHeight app.nWidth app.nDepth], ...
                    Skel{1,i}(:,1), Skel{1,i}(:,2), Skel{1,i}(:,3));
                app.vessData(idx) = i;
            end
            close(d);
            
            % Refresh vessData and endsData
            refreshVessData(app);
            
            % Setup text and sliders
            app.DepthSlider.Limits = [1 app.nDepth];
            app.DepthSlider.Value = 1;
            app.DepthEditField.Value = 1;
            app.StartZEditField.Value = 1;
            app.StopZEditField.Value = app.nDepth;
            
            app.StallCatchersEditorUIFigure.Name = ['StallCatchers Editor - ' app.inFile];
            
            % Refresh all the projection data and update displayed image
            updateImage(app);
            
        end

        % Button pushed function: LoadSingleChStackedTIFFFileButton
        function LoadSingleChStackedTIFFFileButtonPushed(app, event)
            
            % Load ScanImage .tif file
            if isempty(app.inPath)
                [inFile0,inPath0] = uigetfile('*.tif','Select Single-Channel Stacked-TIFF file');
            else
                [inFile0,inPath0] = uigetfile(fullfile(app.inPath,'*.tif'),'Select Single-Channel Stacked-TIFF file');
            end
            figure(app.StallCatchersEditorUIFigure);
            if inFile0 == 0
                return;
            else
                app.inFile = inFile0;
                app.inPath = inPath0;
            end
            
            % Focus back to app
            app.StallCatchersEditorUIFigure.Visible = 'off';
            app.StallCatchersEditorUIFigure.Visible = 'on';
            
            % Load file
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
                'Message','Loading file', 'Indeterminate','on');
            
            % Read stacked-TIFF data
            fileName = fullfile(app.inPath, app.inFile);
            info = imfinfo(fileName);
            im = zeros(info(1).Height,info(1).Width,info(1).SamplesPerPixel, ...
                size(info,1));
            for k = 1:size(info,1)
               im(:,:,:,k) = imread(fileName, k); 
            end
            im = squeeze(mean(im,3));
            
            % Normalize to [1 99.7] saturation levels
            saturated_prctile = [1 99.7]; 
            im = double(im);
            im2 = nonzeros(im .* (~imdilate(im==0, strel('square', 5))));
            p = prctile(im2, saturated_prctile);
            if p(1) == p(2)
                p(1) = min(im(:));
                p(2) = max(im(:));
                if p(1) == p(2)
                    return
                end
            end
            im = (im - p(1) + 1)/(p(2) - p(1) + 2);
            im(:) = min(1, max(0, im(:)));
            
            % Convert to integer
            if info(1).BitDepth==16
                im=uint16(im*double(intmax('uint16')));
            else
                im=uint8(im*double(intmax('uint8')));
            end
            
            % Grab size and store image
            [app.nHeight,app.nWidth,app.nDepth] = size(im);
            app.imData = im;
            
            % Z-range of current image data versus original image data
            app.zRange = [1 app.nDepth];
            
            % Load DeepVess segmentation, if present
            app.dvData = false(app.nHeight,app.nWidth,app.nDepth);
            
            % Create clear volumes
            app.vessData = zeros(app.nHeight,app.nWidth,app.nDepth);
            app.omitData = false(app.nHeight,app.nWidth,app.nDepth);
            app.addData = false(app.nHeight,app.nWidth,app.nDepth);
            
            close(d);
            
            % Refresh vessData and endsData
            refreshVessData(app);
            
            % Setup text and sliders
            app.DepthSlider.Limits = [1 app.nDepth];
            app.DepthSlider.Value = 1;
            app.DepthEditField.Value = 1;
            app.StartZEditField.Value = 1;
            app.StopZEditField.Value = app.nDepth;
            
            app.StallCatchersEditorUIFigure.Name = ['StallCatchers Editor - ' app.inFile];
            
            % Refresh all the projection data and update displayed image
            updateImage(app);
            
        end

        % Button pushed function: SaveEditedAnalysisEditmatFileButton
        function SaveEditedAnalysisEditmatFileButtonPushed(app, event)
            
            % Do nothing if no data is loaded
            if isempty(app.vessData)
                return
            end
            
            % Save DeepVess Analysis .mat file
            OutFile = [app.inFile(1:(end-4)) '-Edit.mat'];
            if ~strcmp(OutFile(1:9),'Analysis-')
                OutFile = ['Analysis-' OutFile];
            end
            if isempty(app.inPath)
                [OutFile,PathName] = uiputfile('Analysis-*.mat', 'Save Analysis .mat file', ...
                    fullfile(app.inPath,OutFile));
            else
                [OutFile,PathName] = uiputfile(fullfile(app.inPath,'Analysis-*.mat'), 'Save Analysis .mat file', ...
                    fullfile(app.inPath,OutFile));
            end
            figure(app.StallCatchersEditorUIFigure);
            if OutFile == 0
                return;
            end
            
            % Focus back to app
            app.StallCatchersEditorUIFigure.Visible = 'off';
            app.StallCatchersEditorUIFigure.Visible = 'on';
            
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
            'Message','Saving file', 'Indeterminate','on');
            
            % Recalculate skeleton and update displayed image
            refreshVessData(app);
            updateImage(app);
            
            % Create output vessel objects
            im = app.imData;
            V = app.dvData;
            CC = bwconncomp(app.vessData>0);
            Skel=cell(3, CC.NumObjects);
            C = zeros(size(app.vessData));
            for i=1:CC.NumObjects
                C(CC.PixelIdxList{i}) = i+1;
                [x,y,z] = ind2sub(size(im), CC.PixelIdxList{i});
                Skel{1,i} = [x,y,z];
                Skel{2,i} = 5;
                Skel{3,i} = i;
            end
            
            % Save results to Analysis mat file
            save(fullfile(PathName,OutFile), 'im','Skel','C','V', '-v7.3');
            
            close(d);
            
        end

        % Button pushed function: SaveProjectedTIFFFileButton
        function SaveProjectedTIFFFileButtonPushed(app, event)
            
            % Do nothing if no data is loaded
            if isempty(app.vessData)
                return
            end
            
            % Get output TIF file name
            if isempty(app.inPath)
                [file,path] = uiputfile('*.tif;*.tiff, TIFF Stack (*.tif,*.tiff)', 'Select TIFF File to Write', ...
                    fullfile(app.inPath,[app.inFile(1:(end-4)) '-prj' num2str(app.ProjectionDepthEditField.Value) '.tif']));
            else
                [file,path] = uiputfile([fullfile(app.inPath,'*.tif') ';' fullfile(app.inPath,'*.tiff') ', TIFF Stack (*.tif,*.tiff)'], 'Select TIFF File to Write', ...
                    fullfile(app.inPath,[app.inFile(1:(end-4)) '-prj' num2str(app.ProjectionDepthEditField.Value) '.tif']));
            end
            figure(app.StallCatchersEditorUIFigure);
            if file == 0
                return;
            end
            filePath = fullfile(path,file);
            
            % Focus back to app
            app.StallCatchersEditorUIFigure.Visible = 'off';
            app.StallCatchersEditorUIFigure.Visible = 'on';
            
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
                'Message','Saving file', 'Value',0);
            
            % Calculate and save stack across each z-frame
            iZ = 1;
            imageZ = calcImageZ(app, iZ);
            imwrite(imageZ, filePath);
            for iZ = 2:app.nDepth
                d.Value = iZ/app.nDepth;
                imageZ = calcImageZ(app, iZ);
                imwrite(imageZ, filePath,'writemode','append');
            end
            
            close(d);
            
        end

        % Button pushed function: RefreshVesselCenterlinesButton
        function RefreshVesselCenterlinesButtonPushed(app, event)
            
            % Do nothing if no data is loaded
            if isempty(app.vessData)
                return
            end
            
            % Recalculate vessel centerlines, and displayed image
            refreshVessData(app);
            updateImage(app);
            
        end

        % Value changed function: DeepVessMaskCheckBox, DepthEditField, 
        % ...and 3 other components
        function DepthEditFieldValueChanged(app, event)
            
            value = round(app.DepthEditField.Value);
            if value < 1
                value = 1;
                app.DepthEditField.Value = value;
            elseif value > app.nDepth
                value = app.nDepth;
                app.DepthEditField.Value = value;
            end
            
            app.DepthSlider.Value = value;
            
            % Update displayed image
            updateImage(app);
            
        end

        % Value changed function: DepthSlider
        function DepthSliderValueChanged(app, event)
            
            value = round(event.Value);
            
            app.DepthEditField.Value = value;
            app.DepthSlider.Value = value;
            
            % Update displayed image
            updateImage(app);
            
        end

        % Value changed function: ProjectionDepthEditField
        function ProjectionDepthEditFieldValueChanged(app, event)
            
            value = round(app.ProjectionDepthEditField.Value);
            app.ProjectionDepthEditField.Value = value;
            if value < 0
                app.ProjectionDepthEditField.Value = 0;
            elseif value > app.nDepth
                app.ProjectionDepthEditField.Value = app.nDepth;
            end
            
            % Update displayed image
            updateImage(app);
            
        end

        % Button pushed function: LineThicknessButton
        function LineThicknessButtonPushed(app, event)
            
            % Change line thickness and reload masks
            prompt = {'Enter a vessel line thickness (in voxels)'};
            dlgtitle = 'Vessel Thickness';
            definput = {num2str(app.vessThickness)};
            answer = inputdlg(prompt,dlgtitle,[1 50],definput);
            
            % Do nothing if cancelled by user
            if isempty(answer)
                return;
            end
            
            % Set new line thickness
            app.vessThickness = round(str2double(answer{1}));
            
            % Update displayed image
            updateImage(app);
            
            % Save results to config file
            lineWidth = app.vessThickness;
            if isfile('StallCatchersEditorConfig.mat')
                save('StallCatchersEditorConfig.mat', 'lineWidth', '-append');
            else
                save('StallCatchersEditorConfig.mat', 'lineWidth');
            end
            
        end

        % Button pushed function: EndpointRadiusButton
        function EndpointRadiusButtonPushed(app, event)
            
            % Change line thickness and reload masks
            prompt = {'Enter an endpoint radius (in voxels)'};
            dlgtitle = 'Endpoint Radius';
            definput = {num2str(app.endPtRadius)};
            answer = inputdlg(prompt,dlgtitle,[1 50],definput);
            
            % Do nothing if cancelled by user
            if isempty(answer)
                return;
            end
            
            % Set new line thickness
            app.endPtRadius = str2double(answer{1});
            
            % Update displayed image
            updateImage(app);
            
            % Save results to config file
            endRadius = app.endPtRadius;
            if isfile('StallCatchersEditorConfig.mat')
                save('StallCatchersEditorConfig.mat', 'endRadius', '-append');
            else
                save('StallCatchersEditorConfig.mat', 'endRadius');
            end
            
        end

        % Button pushed function: LineColorsButton
        function LineColorsButtonPushed(app, event)
            
            % Convert to hexadecimal string
            hexDV = rgb2hex(app, app.rgbDV);
            hexVess = rgb2hex(app, app.rgbVess);
            hexEnds = rgb2hex(app, app.rgbEnds);
            hexOmit = rgb2hex(app, app.rgbOmit);
            hexAdd = rgb2hex(app, app.rgbAdd);
            
            % Ask user for hex colors, using current values as defaults
            prompt = {'DeepVess mask color:', 'Vessel centerline color:', ...
                'Endpoint color:', 'Omited vessel color', ...
                'Added vessel color'};
            dlgtitle = 'Hex Colors';
            dims = [1 40];
            definput = {hexDV, hexVess, hexEnds, hexOmit, hexAdd};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Do nothing if cancelled by user
            if isempty(answer)
                return;
            end
            
            % Convert and store colors
            app.rgbDV = hex2rgb(app, answer{1});
            app.rgbVess = hex2rgb(app, answer{2});
            app.rgbEnds = hex2rgb(app, answer{3});
            app.rgbOmit = hex2rgb(app, answer{4});
            app.rgbAdd = hex2rgb(app, answer{5});
            
            % Update displayed image
            updateImage(app);
            
            % Save results to config file
            lineColours = [app.rgbDV; app.rgbVess; app.rgbEnds];
            if isfile('StallCatchersEditorConfig.mat')
                save('StallCatchersEditorConfig.mat', 'lineColours', '-append');
            else
                save('StallCatchersEditorConfig.mat', 'lineColours');
            end
            
        end

        % Button pushed function: CutStackButton
        function CutStackButtonPushed(app, event)
            
            % Do nothing if no data is loaded
            if isempty(app.vessData)
                return
            end
            
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
                'Message','Applying Cut to Stack', 'Indeterminate','on');
            
            % Store the Z range with respect to the original file Z-range
            app.zRange = [app.StartZEditField.Value app.StopZEditField.Value] + ...
                (app.zRange(1) - 1); % Add on any previous cut from starting Z
            
            % Resize volumes
            app.vessData = app.vessData(:,:,app.StartZEditField.Value:app.StopZEditField.Value);
            app.addData = app.addData(:,:,app.StartZEditField.Value:app.StopZEditField.Value);
            app.omitData = app.omitData(:,:,app.StartZEditField.Value:app.StopZEditField.Value);
            app.dvData = app.dvData(:,:,app.StartZEditField.Value:app.StopZEditField.Value);
            app.imData = app.imData(:,:,app.StartZEditField.Value:app.StopZEditField.Value);
            
            % Update Depth parameters
            app.nDepth = app.StopZEditField.Value - app.StartZEditField.Value + 1;
            app.DepthSlider.Limits = [1 app.nDepth];
            app.DepthSlider.Value = max([1 app.DepthEditField.Value - app.StartZEditField.Value + 1]);
            app.DepthEditField.Value = app.DepthSlider.Value;
            app.StartZEditField.Value = 1;
            app.StopZEditField.Value = app.nDepth;
            
            % Recalculate vessel centerlines, and displayed image
            refreshVessData(app);
            updateImage(app);
            
            close(d);
            
        end

        % Value changed function: MinVesselLengthEditField
        function MinVesselLengthEditFieldValueChanged(app, event)
            
            app.minBranchLength = app.MinVesselLengthEditField.Value;
            
        end

        % Button pushed function: SelectROISButton
        function SelectROISButtonPushed(app, event)
            
            if isempty(app.vessData)
                return
            end
            
            % Toggled off other buttons
            app.ConnectingCButton.Value = false;
            app.IndividualRButton.Value = false;
            app.DrawLinesDButton.Value = false;
            app.EraserEButton.Value = false;
            DrawLinesDButtonValueChanged(app);
            EraserEButtonValueChanged(app);
            
            delete(app.ROI);
            
            switch app.DropDown.Value
                case 'Rectangle'
                    % Draw rectange ROI
                    app.ROI = drawrectangle(app.UIAxes);
                case 'Circle'
                    % Draw rectange ROI
                    app.ROI = drawcircle(app.UIAxes);
                case 'Polygon'
                    % Draw rectange ROI
                    app.ROI = drawpolygon(app.UIAxes);
                case 'Freehand'
                    % Draw rectange ROI
                    app.ROI = drawfreehand(app.UIAxes);
            end
            
        end

        % Button pushed function: ROIExcludeButton
        function ROIExcludeButtonPushed(app, event)
            
            if isempty(app.ROI)
                return
            end
            
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
                'Message','Removing Selected Vessel Segments', 'Indeterminate','on');
            
            % Create mask from
            mROI = createMask(app.ROI,app.vessData(:,:,1));
            
            % Current z-projection range
            iDepth = app.DepthSlider.Value;
            rDepth = max([1 iDepth - app.ProjectionDepthEditField.Value]) ...
                : min([app.nDepth iDepth + app.ProjectionDepthEditField.Value]);
            
            % Vessel num data in current projection
            vessNum = max(app.vessData(:,:,rDepth),[],3);
            
            % List of vessel numbers within ROI
            vessList = unique(vessNum(mROI));
            vessList(vessList==0) = []; % Remove '0'
            
            % Add vessel voxels to app.omitData
            close(d);
            d = uiprogressdlg(app.StallCatchersEditorUIFigure, 'Title','Working on it...', ...
                'Message','Removing Selected Vessel Segments', 'Value',0);
            iVess = 0;
            nVess = length(vessList);
            for value = vessList'
                iVess = iVess + 1;
                d.Value = iVess/nVess;
                app.omitData = app.omitData | (app.vessData == value);
            end
            
            % Clear ROI
            delete(app.ROI);
            close(d);
            
            % Update displayed image
            updateImage(app);
            
        end

        % Value changed function: ConnectingCButton
        function ConnectingCButtonValueChanged(app, event)
            value = app.ConnectingCButton.Value;
            
            if value % Toggled on
                app.DrawLinesDButton.Value = false;
                app.EraserEButton.Value = false;
                app.IndividualRButton.Value = false;
                DrawLinesDButtonValueChanged(app);
                EraserEButtonValueChanged(app);
            end
            
        end

        % Value changed function: DrawLinesDButton
        function DrawLinesDButtonValueChanged(app, event)
            value = app.DrawLinesDButton.Value;
            
            if value % Toggled on
                app.EraserEButton.Value = false;
                app.IndividualRButton.Value = false;
                app.ConnectingCButton.Value = false;
                EraserEButtonValueChanged(app);
            else
                app.YXZ0 = [];
            end
            
        end

        % Value changed function: EraserEButton
        function EraserEButtonValueChanged(app, event)
            value = app.EraserEButton.Value;
            
            if value % Toggled on
                app.DrawLinesDButton.Value = false;
                app.IndividualRButton.Value = false;
                app.ConnectingCButton.Value = false;
                DrawLinesDButtonValueChanged(app);
            else % Toggled off
                % Erase circle
                cla(app.UIAxesCursor);
            end
            
        end

        % Value changed function: IndividualRButton
        function IndividualRButtonValueChanged(app, event)
            value = app.IndividualRButton.Value;
            
            if value % Toggled on
                app.DrawLinesDButton.Value = false;
                app.EraserEButton.Value = false;
                app.ConnectingCButton.Value = false;
                DrawLinesDButtonValueChanged(app);
                EraserEButtonValueChanged(app);
            end
            
        end

        % Value changed function: FactorEditField
        function FactorEditFieldValueChanged(app, event)
            
            if app.FactorEditField.Value < 0
                app.FactorEditField.Value = 1.1;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StallCatchersEditorUIFigure = uifigure('Visible', 'off');
            app.StallCatchersEditorUIFigure.Name = 'StallCatchers Editor';
            app.StallCatchersEditorUIFigure.SizeChangedFcn = createCallbackFcn(app, @StallCatchersEditorUIFigureSizeChanged, true);
            app.StallCatchersEditorUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @StallCatchersEditorUIFigureWindowButtonDown, true);
            app.StallCatchersEditorUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @StallCatchersEditorUIFigureWindowButtonUp, true);
            app.StallCatchersEditorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @StallCatchersEditorUIFigureWindowButtonMotion, true);
            app.StallCatchersEditorUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @StallCatchersEditorUIFigureWindowScrollWheel, true);
            app.StallCatchersEditorUIFigure.KeyPressFcn = createCallbackFcn(app, @StallCatchersEditorUIFigureKeyPress, true);
            app.StallCatchersEditorUIFigure.KeyReleaseFcn = createCallbackFcn(app, @StallCatchersEditorUIFigureKeyRelease, true);
            app.ViewPanel = uipanel(app.StallCatchersEditorUIFigure);
            app.LineThicknessButton = uibutton(app.ViewPanel, 'push');
            app.LineThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @LineThicknessButtonPushed, true);
            app.LineThicknessButton.Text = 'Line Thickness';
            app.LineColorsButton = uibutton(app.ViewPanel, 'push');
            app.LineColorsButton.ButtonPushedFcn = createCallbackFcn(app, @LineColorsButtonPushed, true);
            app.LineColorsButton.Text = 'Line Colors';
            app.DeepVessMaskCheckBox = uicheckbox(app.ViewPanel);
            app.DeepVessMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @DepthEditFieldValueChanged, true);
            app.DeepVessMaskCheckBox.Text = 'DeepVess Mask';
            app.VesselsCheckBox = uicheckbox(app.ViewPanel);
            app.VesselsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DepthEditFieldValueChanged, true);
            app.VesselsCheckBox.Text = 'Vessel Centerlines';
            app.VesselsCheckBox.Value = true;
            app.EndPointsCheckBox = uicheckbox(app.ViewPanel);
            app.EndPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DepthEditFieldValueChanged, true);
            app.EndPointsCheckBox.Text = 'Vessel Endpoints';
            app.EndPointsCheckBox.Value = true;
            app.DepthPlaneCheckBox = uicheckbox(app.ViewPanel);
            app.DepthPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @DepthEditFieldValueChanged, true);
            app.DepthPlaneCheckBox.Text = {'Depth Plane'; 'Highlighting'};
            app.DepthPlaneCheckBox.Value = true;
            app.EndpointRadiusButton = uibutton(app.ViewPanel, 'push');
            app.EndpointRadiusButton.ButtonPushedFcn = createCallbackFcn(app, @EndpointRadiusButtonPushed, true);
            app.EndpointRadiusButton.Text = 'Endpoint Radius';
            app.ProjectionDepthLabel = uilabel(app.ViewPanel);
            app.ProjectionDepthLabel.Text = {'Projection'; '± Depth'};
            app.ProjectionDepthEditField = uieditfield(app.ViewPanel, 'numeric');
            app.ProjectionDepthEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectionDepthEditFieldValueChanged, true);
            app.ProjectionDepthEditField.Value = 7;
            app.DViewerPanel = uipanel(app.ViewPanel);
            app.DViewerPanel.Title = '3D Viewer';
            app.Plot3DButton = uibutton(app.DViewerPanel, 'state');
            app.Plot3DButton.Text = {'Select center'; 'and plot in 3D'};
            app.XYwidthpxLabel = uilabel(app.DViewerPanel);
            app.XYwidthpxLabel.Text = 'X-Y width [px]';
            app.Plot3DXYWidthEditField = uieditfield(app.DViewerPanel, 'numeric');
            app.Plot3DXYWidthEditField.Value = 50;
            app.BackgroundLabel = uilabel(app.DViewerPanel);
            app.BackgroundLabel.Text = 'Background %';
            app.Plot3DBkPctEditField = uieditfield(app.DViewerPanel, 'numeric');
            app.Plot3DBkPctEditField.Value = 50;
            app.ZdepthpxLabel = uilabel(app.DViewerPanel);
            app.ZdepthpxLabel.Text = 'Z depth [px]';
            app.Plot3DZDepthEditField = uieditfield(app.DViewerPanel, 'numeric');
            app.Plot3DZDepthEditField.Value = 20;
            app.ZoomPanel = uipanel(app.ViewPanel);
            app.ZoomPanel.Title = 'Zoom';
            app.FactorEditFieldLabel = uilabel(app.ZoomPanel);
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.ZoomPanel, 'numeric');
            app.FactorEditField.ValueChangedFcn = createCallbackFcn(app, @FactorEditFieldValueChanged, true);
            app.FactorEditField.Value = 1.5;
            app.EditPanel = uipanel(app.StallCatchersEditorUIFigure);
            app.CutStackPanel = uipanel(app.EditPanel);
            app.CutStackPanel.Title = 'Cut Stack';
            app.StartZEditFieldLabel = uilabel(app.CutStackPanel);
            app.StartZEditFieldLabel.Text = 'Start Z';
            app.StartZEditField = uieditfield(app.CutStackPanel, 'numeric');
            app.StartZEditField.Value = 1;
            app.StopZEditFieldLabel = uilabel(app.CutStackPanel);
            app.StopZEditFieldLabel.Text = 'Stop Z';
            app.StopZEditField = uieditfield(app.CutStackPanel, 'numeric');
            app.StopZEditField.Value = 1;
            app.CutStackButton = uibutton(app.CutStackPanel, 'push');
            app.CutStackButton.ButtonPushedFcn = createCallbackFcn(app, @CutStackButtonPushed, true);
            app.CutStackButton.Tooltip = {'Remove frames below ''Start Z'' and above ''Stop Z''.'; '(irreversible)'};
            app.CutStackButton.Text = 'Apply Cut';
            app.RefreshVesselCenterlinesButton = uibutton(app.EditPanel, 'push');
            app.RefreshVesselCenterlinesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshVesselCenterlinesButtonPushed, true);
            app.RefreshVesselCenterlinesButton.Tooltip = {'Incorporate all changes and recalculate stack.'; '(irreversible)'};
            app.RefreshVesselCenterlinesButton.Text = {'Refresh'; 'Vessel'; 'Centerlines'};
            app.MinVesselLengthpxEditFieldLabel = uilabel(app.EditPanel);
            app.MinVesselLengthpxEditFieldLabel.Text = {'Min Vessel'; 'Length [px]'};
            app.MinVesselLengthEditField = uieditfield(app.EditPanel, 'numeric');
            app.MinVesselLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinVesselLengthEditFieldValueChanged, true);
            app.MinVesselLengthEditField.Tooltip = {'Any vessel segments shorter than this are removed.'};
            app.MinVesselLengthEditField.Value = 10;
            app.DrawVesselLinesPanel = uipanel(app.EditPanel);
            app.DrawVesselLinesPanel.Title = 'Draw Vessel Lines';
            app.EraserWidthpxLabel = uilabel(app.DrawVesselLinesPanel);
            app.EraserWidthpxLabel.Text = {'Eraser Width'; '[px]'};
            app.EraserWidthpxEditField = uieditfield(app.DrawVesselLinesPanel, 'numeric');
            app.EraserWidthpxEditField.Value = 50;
            app.EraserEButton = uibutton(app.DrawVesselLinesPanel, 'state');
            app.EraserEButton.ValueChangedFcn = createCallbackFcn(app, @EraserEButtonValueChanged, true);
            app.EraserEButton.Tooltip = {'Left-Click (and drag) to remove drawn or add vessel lines.'};
            app.EraserEButton.Text = 'Eraser [E]';
            app.DrawLinesDButton = uibutton(app.DrawVesselLinesPanel, 'state');
            app.DrawLinesDButton.ValueChangedFcn = createCallbackFcn(app, @DrawLinesDButtonValueChanged, true);
            app.DrawLinesDButton.Tooltip = {'Left-click in sequence to create a continuous line.'; 'Right-click to end the line at the last point.'};
            app.DrawLinesDButton.Text = 'Draw Lines [D]';
            app.RemoveSegmentsPanel = uipanel(app.EditPanel);
            app.RemoveSegmentsPanel.Title = 'Remove Segments';
            app.ROIExcludeButton = uibutton(app.RemoveSegmentsPanel, 'push');
            app.ROIExcludeButton.ButtonPushedFcn = createCallbackFcn(app, @ROIExcludeButtonPushed, true);
            app.ROIExcludeButton.Tooltip = {'Omit all vessel segments in the currently selected ROI'};
            app.ROIExcludeButton.Text = 'Exclude ROI [X]';
            app.DropDown = uidropdown(app.RemoveSegmentsPanel);
            app.DropDown.Items = {'Rectangle', 'Circle', 'Polygon', 'Freehand'};
            app.DropDown.Value = 'Rectangle';
            app.SelectROISButton = uibutton(app.RemoveSegmentsPanel, 'push');
            app.SelectROISButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROISButtonPushed, true);
            app.SelectROISButton.Tooltip = {'Create an ROI to omit all included vessel segments'};
            app.SelectROISButton.Text = 'Select ROI [S]';
            app.IndividualRButton = uibutton(app.RemoveSegmentsPanel, 'state');
            app.IndividualRButton.ValueChangedFcn = createCallbackFcn(app, @IndividualRButtonValueChanged, true);
            app.IndividualRButton.Tooltip = {'Left-Click to omit an individual vessel segment'; 'Right-click to re-add an individual vessel segment'};
            app.IndividualRButton.Text = 'Individual [R]';
            app.ConnectEndpointsPanel = uipanel(app.EditPanel);
            app.ConnectEndpointsPanel.Title = 'Connect Endpoints';
            app.ConnectingCButton = uibutton(app.ConnectEndpointsPanel, 'state');
            app.ConnectingCButton.ValueChangedFcn = createCallbackFcn(app, @ConnectingCButtonValueChanged, true);
            app.ConnectingCButton.Tooltip = {'Left-Click two endpoints to connect them.'; 'Right-click after first left-click to cancel first selected endpoint.'};
            app.ConnectingCButton.Text = 'Connecting [C]';
            app.PanelSelectButtonGroup = uibuttongroup(app.StallCatchersEditorUIFigure);
            app.PanelSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PanelSelectButtonGroupSelectionChanged, true);
            app.PanelSelectButtonGroup.Title = 'Panel Select';
            app.FileButton = uitogglebutton(app.PanelSelectButtonGroup);
            app.FileButton.Text = 'File';
            app.FileButton.Value = true;
            app.EditButton = uitogglebutton(app.PanelSelectButtonGroup);
            app.EditButton.Text = 'Edit';
            app.ViewButton = uitogglebutton(app.PanelSelectButtonGroup);
            app.ViewButton.Text = 'View';
            app.AxisPanel = uipanel(app.StallCatchersEditorUIFigure);
            app.UIAxes = uiaxes(app.AxisPanel);
            app.UIAxes.XLim = [1 1024];
            app.UIAxes.YLim = [1 1024];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxesCursor = uiaxes(app.AxisPanel);
            app.UIAxesCursor.XLim = [1 1024];
            app.UIAxesCursor.YLim = [1 1024];
            app.UIAxesCursor.XTick = [];
            app.UIAxesCursor.YTick = [];
            app.UIAxesCursor.Visible = 'off';
            app.DepthEditField = uieditfield(app.AxisPanel, 'numeric');
            app.DepthEditField.ValueChangedFcn = createCallbackFcn(app, @DepthEditFieldValueChanged, true);
            app.DepthEditField.Value = 1;
            app.DepthSliderLabel = uilabel(app.AxisPanel);
            app.DepthSliderLabel.Text = 'Depth';
            app.DepthSlider = uislider(app.AxisPanel);
            app.DepthSlider.Limits = [1 100];
            app.DepthSlider.ValueChangedFcn = createCallbackFcn(app, @DepthSliderValueChanged, true);
            app.DepthSlider.Value = 1;
            app.FilePanel = uipanel(app.StallCatchersEditorUIFigure);
            app.LoadAnalysismatFileButton = uibutton(app.FilePanel, 'push');
            app.LoadAnalysismatFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnalysismatFileButtonPushed, true);
            app.LoadAnalysismatFileButton.Text = {'Load'; '''Analysis-*.mat'''; 'File'};
            app.SaveEditedAnalysisEditmatFileButton = uibutton(app.FilePanel, 'push');
            app.SaveEditedAnalysisEditmatFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEditedAnalysisEditmatFileButtonPushed, true);
            app.SaveEditedAnalysisEditmatFileButton.Text = {'Save Edited'; '''Analysis-*-Edit.mat'''; 'File'};
            app.SaveProjectedTIFFFileButton = uibutton(app.FilePanel, 'push');
            app.SaveProjectedTIFFFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectedTIFFFileButtonPushed, true);
            app.SaveProjectedTIFFFileButton.Text = {'Save Projected'; 'TIFF File'};
            app.LoadSingleChStackedTIFFFileButton = uibutton(app.FilePanel, 'push');
            app.LoadSingleChStackedTIFFFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSingleChStackedTIFFFileButtonPushed, true);
            app.LoadSingleChStackedTIFFFileButton.Text = {'Load Single-Ch'; 'Stacked-TIFF'; 'File'};
            % Show the figure after all components are created
            app.StallCatchersEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StallCatchersEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StallCatchersEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StallCatchersEditorUIFigure)
        end
    end
end